classdef FEM_Project_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DisplayEdgesSwitch             matlab.ui.control.Switch
        DisplayEdgesLabel              matlab.ui.control.Label
        StressTypeDropDown_2           matlab.ui.control.DropDown
        HowtouseButton_2               matlab.ui.control.Button
        Clear1DAnalysisButton          matlab.ui.control.Button
        Clear2DAnalysisButton          matlab.ui.control.Button
        Run1DAnalysisButton            matlab.ui.control.Button
        Run2DAnalysisButton            matlab.ui.control.Button
        HowtouseButton                 matlab.ui.control.Button
        StressTypeDropDown             matlab.ui.control.DropDown
        ButtonGroup                    matlab.ui.container.ButtonGroup
        Autumn                         matlab.ui.control.ToggleButton
        Parula                         matlab.ui.control.ToggleButton
        Bone                           matlab.ui.control.ToggleButton
        Jet                            matlab.ui.control.ToggleButton
        DeformationMagnifierEditField  matlab.ui.control.NumericEditField
        DeformationMagnifierLabel      matlab.ui.control.Label
        ofElementsEditField            matlab.ui.control.NumericEditField
        ofElementsEditFieldLabel       matlab.ui.control.Label
        ofNodesEditField               matlab.ui.control.NumericEditField
        ofNodesEditFieldLabel          matlab.ui.control.Label
        InclusionLabel                 matlab.ui.control.Label
        BodyLabel                      matlab.ui.control.Label
        vEditField_Inc                 matlab.ui.control.NumericEditField
        vEditField_2Label              matlab.ui.control.Label
        EEditField_Inc                 matlab.ui.control.NumericEditField
        EEditField_2Label              matlab.ui.control.Label
        vEditField                     matlab.ui.control.NumericEditField
        vEditFieldLabel                matlab.ui.control.Label
        EEditField                     matlab.ui.control.NumericEditField
        EEditFieldLabel                matlab.ui.control.Label
        ElementTypeDropDown            matlab.ui.control.DropDown
        ElementTypeLabel               matlab.ui.control.Label
        Switch_3                       matlab.ui.control.Switch
        Switch_2                       matlab.ui.control.Switch
        Switch                         matlab.ui.control.Switch
        TabGroup2                      matlab.ui.container.TabGroup
        BeamWithNoVoidTab              matlab.ui.container.Tab
        Beam_dEditField                matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        Beam_cEditField                matlab.ui.control.NumericEditField
        cEditFieldLabel                matlab.ui.control.Label
        Beam_bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        Beam_aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        BeamShear                      matlab.ui.control.Button
        BeamExpansion                  matlab.ui.control.Button
        BeamExtension                  matlab.ui.control.Button
        BeamPlotButton                 matlab.ui.control.Button
        CircleTab                      matlab.ui.container.Tab
        CircleShear                    matlab.ui.control.Button
        CircleExpansion                matlab.ui.control.Button
        CircleExtension                matlab.ui.control.Button
        Circle_rEditField              matlab.ui.control.NumericEditField
        rEditFieldLabel                matlab.ui.control.Label
        Circle_dEditField              matlab.ui.control.NumericEditField
        dEditFieldLabel_2              matlab.ui.control.Label
        Circle_cEditField              matlab.ui.control.NumericEditField
        cEditFieldLabel_2              matlab.ui.control.Label
        Circle_bEditField              matlab.ui.control.NumericEditField
        bEditFieldLabel_2              matlab.ui.control.Label
        Circle_aEditField              matlab.ui.control.NumericEditField
        aEditFieldLabel_2              matlab.ui.control.Label
        CirclePlotButton               matlab.ui.control.Button
        SquareTab                      matlab.ui.container.Tab
        Square_eEditField              matlab.ui.control.NumericEditField
        eLabel                         matlab.ui.control.Label
        Square_dEditField              matlab.ui.control.NumericEditField
        dEditFieldLabel_3              matlab.ui.control.Label
        Square_cEditField              matlab.ui.control.NumericEditField
        cEditFieldLabel_3              matlab.ui.control.Label
        Square_bEditField              matlab.ui.control.NumericEditField
        bEditFieldLabel_3              matlab.ui.control.Label
        Square_aEditField              matlab.ui.control.NumericEditField
        aEditFieldLabel_3              matlab.ui.control.Label
        SquareShear                    matlab.ui.control.Button
        SquareExpansion                matlab.ui.control.Button
        SquareExtension                matlab.ui.control.Button
        SquarePlotButton               matlab.ui.control.Button
        RhombusTab                     matlab.ui.container.Tab
        Rhombus_eEditField             matlab.ui.control.NumericEditField
        eLabel_2                       matlab.ui.control.Label
        Rhombus_dEditField             matlab.ui.control.NumericEditField
        dEditFieldLabel_4              matlab.ui.control.Label
        Rhombus_cEditField             matlab.ui.control.NumericEditField
        cEditFieldLabel_4              matlab.ui.control.Label
        Rhombus_bEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel_4              matlab.ui.control.Label
        Rhombus_aEditField             matlab.ui.control.NumericEditField
        aEditFieldLabel_4              matlab.ui.control.Label
        RhombusShear                   matlab.ui.control.Button
        RhombusExpansion               matlab.ui.control.Button
        RhombusExtension               matlab.ui.control.Button
        RhombusPlotButton              matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)


        DimensionSwitch = "2D";
        element_type = "D2TR3N";
        BCType = "Extension"
        stressswitch = "Stress_xx"
        DispDrop_2 = "Total Displacement"

        dimension = 2;
        NodeSize = 11;

        inculisonSwitch = "Void";
        inc = 0;

        colormap = "turbo"

        p = 4;
        m = 4;

        d1 = 1,
        d2 = 1;

        PD = 2;

        A = 0.01;
        E = 10^6;
        ro = 3.5;
        
        Uniform_a = 1;
        Uniform_b = 1;
        Uniform_c = 6;
        Uniform_d = 5;

        circle_a = 1;
        circle_b = 1;
        circle_c = 4;
        circle_d = 4;
        circle_r = 0.2;

        square_a = 1;
        square_b = 1;
        square_c = 4;
        square_d = 4;
        square_e = 0.2;

        rhombus_a = 1;
        rhombus_b = 1;
        rhombus_c = 4;
        rhombus_d = 4;
        rhombus_e = 0.2;

        NoN = [];
        NoE = [];
        NL = [];
        EL = [];
        ENL = [];
        nl = [];

        NoN2 = [];
        NoE2 = [];
        NL2 = [];
        EL2 = [];
        NPE2 = [];

        EV = [1 0.3; 10 0.3];
        magnitude = 0.1;


        Force13 = 350;

        edgedisp = "On";
        edgec = "k";


        ENLFirst = []
        ENLfirst1112 = []
        UseOpt2 = []
        ENLTemp = []
        
        DispDrop = "Total Displacement"
        

        Score_Based = "On"


        DOFs 
        DOCs 

        FixHorizontal = "On";


        Circle_a 
        Circle_b 
        Circle_c 
        Circle_d 
        Circle_r 
        
       
        Up = []
        Fp = []
        Fu = []
        Uu = []


        Up2 = []
        Fp2 = []
        Fu2 = []
        Uu2 = []
        nl2 = []

        Up3 = []
        Fp3 = []
        Fu3 = []
        Uu3 = []
        NoN3

        TotalW = 0;
        TotalW_A = 0;


        Knob_2value = "Tiny";
        
        ElementSwitch_2 = 'Off';
        NodeSwitch_2 = 'Off';
        NodeIndicator_2 = "Off";
        NodeIndicatorNO = "Off";
        mag = 25;

        BCvalue = 0
        BCvalue13 = 0
        NodeNumber = 0
        NodeNumber13 = 0
        BCforcedisplacementSwitch = "D"
        BCforcedisplacementSwitch13 = "D"
        BCdirectionSwitch = "X"
        BCdirectionSwitch13 = "X"
        NodeSwitch = 'On'
        ElementSwitch = "On"
        NodeIndicator = "On"
        ElementIndicator = "On"

        OPTNodeSwitch = 'Off'
        OPTElementSwitch = "Off"
        OPTNodeIndicator = "Off"
        OPTElementIndicator = "Off"

        colorMap = jet


    end


    methods (Access = public)


        function assign_BCs2(app)

            
            app.NoN = size(app.NL,1);
            app.PD = size(app.NL,2);
            
            if size(app.ENL,2) ~= 12

                app.ENL = zeros(app.NoN, 12);
            end

            app.ENL = app.ENL(1:size(app.NL,1),:);

            app.ENL(:,1:2) = app.NL;


            hold(app.UIAxes,'on');
           
            if app.NodeNumber ~= 0
                switch app.BCdirectionSwitch
                    case 'X'
                        switch app.BCforcedisplacementSwitch
                            case 'F'
                                hold(app.UIAxes,'on');
                                app.ENL(app.NodeNumber,11) = app.BCvalue;
                                app.ENL(app.NodeNumber,3) = 1;
                                if app.BCvalue > 0
                                    plot(app.UIAxes,linspace(app.NL(app.NodeNumber,1)+0.1,(app.NL(app.NodeNumber,1)+0.5),5),app.NL(app.NodeNumber,2),">",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)+0.6,app.NL(app.NodeNumber,2),num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                elseif app.BCvalue < 0
                                    plot(app.UIAxes,linspace(app.NL(app.NodeNumber,1)-0.1,(app.NL(app.NodeNumber,1)-0.5),5),app.NL(app.NodeNumber,2),"<",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)-0.65,app.NL(app.NodeNumber,2),num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                end
                            case 'D'
                                hold(app.UIAxes,'on');
                                if app.BCvalue == 0

                                    app.ENL(app.NodeNumber,9) = app.BCvalue;
                                    app.ENL(app.NodeNumber,3) = -1;
                                    hold(app.UIAxes,'on');
                                    xp = [app.NL(app.NodeNumber,1) (app.NL(app.NodeNumber,1)-0.25) (app.NL(app.NodeNumber,1)-0.25)];
                                    yp = [app.NL(app.NodeNumber,2) (app.NL(app.NodeNumber,2)+0.125) (app.NL(app.NodeNumber,2)-0.125)];
                                    patch(app.UIAxes,xp, yp, 'w', "FaceAlpha",.5)
                                else
                                    app.ENL(app.NodeNumber,9) = app.BCvalue;
                                    app.ENL(app.NodeNumber,3) = -1;
                                end

                        end

                    case 'Y'
                        switch app.BCforcedisplacementSwitch
                            case 'F'
                                app.ENL(app.NodeNumber,12) = app.BCvalue;
                                app.ENL(app.NodeNumber,4) = 1;
                                if app.BCvalue > 0
                                    plot(app.UIAxes,app.NL(app.NodeNumber,1),linspace(app.NL(app.NodeNumber,2)+0.1,app.NL(app.NodeNumber,2)+0.5,5),"^",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)-0.03,app.NL(app.NodeNumber,2)+0.6,num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                elseif app.BCvalue < 0
                                    plot(app.UIAxes,app.NL(app.NodeNumber,1),linspace(app.NL(app.NodeNumber,2)-0.1,app.NL(app.NodeNumber,2)-0.5,5),"v",'MarkerSize',(app.NodeSize-10),"LineWidth",2,"Color","r","MarkerFaceColor",[.96 .99 .45])
                                    text(app.UIAxes,app.NL(app.NodeNumber,1)-0.03,app.NL(app.NodeNumber,2)-0.6,num2str(app.BCvalue) + " N",'Color','y','FontSize',25,'FontWeight','bold')
                                end

                            case 'D'
                                hold(app.UIAxes,'on');
                                if app.BCvalue == 0

                                    app.ENL(app.NodeNumber,10) = app.BCvalue;
                                    app.ENL(app.NodeNumber,4) = -1;
                                    hold(app.UIAxes,'on');
                                    xp2 = [app.NL(app.NodeNumber,1) (app.NL(app.NodeNumber,1)-0.125) (app.NL(app.NodeNumber,1)+0.125)];
                                    yp2 = [app.NL(app.NodeNumber,2) (app.NL(app.NodeNumber,2)-0.25) (app.NL(app.NodeNumber,2)-0.25)];
                                    patch(app.UIAxes,xp2, yp2, 'w',"FaceAlpha",.5)
                                else
                                    app.ENL(app.NodeNumber,10) = app.BCvalue;
                                    app.ENL(app.NodeNumber,4) = -1;
                                end


                        end
                end
            end


            app.DOFs = 0;
            app.DOCs = 0;
            
            for i = 1:app.NoN
                for j = 1:app.PD
                    if (app.ENL(i,(app.PD+j)) == -1)
            
                        app.DOCs = app.DOCs - 1;
                        app.ENL(i, 2*app.PD+j) = app.DOCs;
                    else
                        app.DOFs = app.DOFs + 1;
                        app.ENL(i, 2*app.PD+j) = app.DOFs;
                    end
                end
            end
            
            
            for i = 1:app.NoN
                for j= 1:app.PD
                    if (app.ENL(i,(2*app.PD+j)) < 0 )
            
                        app.ENL(i, 3*app.PD+j) = app.DOFs + abs(app.ENL(i,(2*app.PD+j)));
                    else
                       
                        app.ENL(i, 3*app.PD+j) = app.ENL(i,2*app.PD+j);
                    end
                end
            end
            
            app.DOCs = abs(app.DOCs);
            
        end


        function [K] = assemble_stiffness2(app)

                app.NoE = size(app.EL,1);
                NPE = size(app.NL,2);
                
                app.NoN = size(app.NL,1);
                app.PD = size(app.NL,2);
                
                K = zeros(app.NoN*app.PD, app.NoN*app.PD);
                
                
                for i = 1:app.NoE
                
                    app.nl = app.EL(i, 1:NPE);

                    k = element_stiffness2(app);
                
                    for r = 1:NPE 
                        for a = 1:app.PD
                            for q = 1:NPE
                                for s = 1:app.PD
                
                                    row = app.ENL(app.nl(r), a+3*app.PD);
                                    column = app.ENL(app.nl(q),s+3*app.PD);
                                    
                
                                    value = k((r-1)*app.PD+a, (q-1)*app.PD+s);
                                    K(row,column) = K(row,column) + value;
                
                                end
                            end
                        end
                    end
                end
                
        end

        function k = element_stiffness2(app)
            X(1) = app.ENL(app.nl(1),1);
            Y(1) = app.ENL(app.nl(1),2);
            X(2) = app.ENL(app.nl(2),1);
            Y(2) = app.ENL(app.nl(2),2);
            
            L = sqrt((X(1)-X(2))^2 + (Y(1)-Y(2))^2);
            
            C = (X(2)-X(1))/L;
            S = (Y(2)-Y(1))/L;
            
            k = ((app.A*app.E)/L) * [C^2 C*S -C^2 -C*S
                                     C*S S^2 -C*S -S^2
                                     -C^2 -C*S C^2 C*S
                                     -C*S -S^2 C*S S^2];
        end
        

        function assemble_forces2(app)
    
                app.NoN = size(app.NL,1);
                app.PD = size(app.NL,2);
                
                app.DOFs = 0;
                app.Fp = [];
                app.ENL
                
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i, app.PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.Fp(app.DOFs,1) = app.ENL(i,5*app.PD+j);
                
                        end
                    end
                end
            end
        
        
            function assemble_displacements2(app)
    
                app.NoN = size(app.NL,1);
                app.PD = size(app.NL,2);
                
                app.DOCs = 0;
                app.Up = [];
                
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i, app.PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.Up(app.DOCs,1) = app.ENL(i,4*app.PD+j);
                       
                        end
                    end
                end
            end


            function update_nodes2(app)
    
                app.NoN = size(app.NL,1);
                app.PD = size(app.NL,2);
                app.DOFs = 0;
                app.DOCs = 0;
                
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i, app.PD+j) == 1
                
                            app.DOFs = app.DOFs +1;
                            app.ENL(i,4*app.PD+j) = app.Uu(app.DOFs);
                         end
                    end
                end
                
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i, app.PD+j) == -1
                
                            app.DOCs = app.DOCs +1;
                            app.ENL(i,5*app.PD+j) = app.Fu(app.DOCs);
                
                        end
                    end
                end
            end


    function uniform_mesh_1D(app)  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        cla(app.UIAxes)

        app.NodeSize = 11;

        app.PD = 2;
        
        q = [0 0; app.Uniform_a 0; 0 app.Uniform_b; app.Uniform_a app.Uniform_b];
        
        app.NoN = (app.Uniform_c+1)*(app.Uniform_d+1);
        
        app.NoE = app.Uniform_c*app.Uniform_d;
        
        NPE = 4;
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   Nodes   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        app.NL = zeros(app.NoN, app.PD);
        
        a = app.Uniform_a/app.Uniform_c;
        b = app.Uniform_b/app.Uniform_d;
        
        n=1;
        
        for i = 1:app.Uniform_d+1
            
           for j = 1:app.Uniform_c+1
               app.NL(n,1) = q(1,1) + (j-1)*a;
               app.NL(n,2) = q(1,2) + (i-1)*b;
               n = n+1;
               
           end
           
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%   Elements   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        for i = 1:app.NoN
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
            text(app.UIAxes, app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
        end
        
        axis(app.UIAxes,"equal")
    
        Node_Multiplier = app.Uniform_c+1;
        app.EL = [];
    
        for i = 0:(app.Uniform_c-1)
            for j = 1:app.Uniform_c
                app.EL(size(app.EL,1)+1,:) = [0 0];
                app.EL(size(app.EL,1),:) = [(Node_Multiplier*i)+j (Node_Multiplier*i)+j+1];
    
            end
        end
    
        for i = 1:(app.Uniform_c+1)
            for j = 0:(app.Uniform_c-2)
                app.EL(size(app.EL,1)+1,:) = [0 0];
                app.EL(size(app.EL,1),:) = [i+(j*Node_Multiplier) i+(j*Node_Multiplier)+app.Uniform_c+1];
    
            end
        end
    
        for i = 1:app.Uniform_c
            for j = 0:(app.Uniform_c-2)
                app.EL(size(app.EL,1)+1,:) = [0 0];
                app.EL(size(app.EL,1),:) = [i+(j*Node_Multiplier) i+(j*Node_Multiplier)+app.Uniform_c+2];
    
            end
        end
    
        for i = 2:app.Uniform_c+1
            for j = 0:(app.Uniform_c-2)
                app.EL(size(app.EL,1)+1,:) = [0 0];
                app.EL(size(app.EL,1),:) = [i+(j*Node_Multiplier) i+(j*Node_Multiplier)+app.Uniform_c];
    
            end
        end
    
        app.EL = unique(app.EL,"rows");


        for i=1 : size(app.EL,1)
            H = plot(app.UIAxes,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
            H.Color(4) = 0.2;
        
        end

        app.ofNodesEditField.Value = size(app.NL,1);
        app.ofElementsEditField.Value = size(app.EL,1);
 
    end


    function Void_Circle_Mesher_1D(app)    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        app.NodeSize = 11;

        cla(app.UIAxes)
        
        d1 = app.circle_a;
        d2 = app.circle_b;
        p = app.circle_c;
        m = app.circle_d;
        R = app.circle_r;
    
    
        q = [0 0; d1 0; 0 d2; d1 d2];
        
        app.PD = 2;
        
        app.NoN = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
        
        app.NoE = 4*p*m;
        
        NPE = 4;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   Nodes   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        app.NL = zeros(app.NoN, app.PD);
        
        a = d1/p;
        b = d2/p;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 1  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        coor11 = zeros((p+1)*(m+1), app.PD);
        
        for i = 1:p+1
            
            coor11(i,1) = q(1,1) + (i-1)*a;
            coor11(i,2) = q(1,2);
            
        end
        
        for i = 1:p+1
            
            coor11(m*(p+1)+i,1) = R*cos((5*pi/4) + (i-1)*(pi/2)/p) + (d1/2);
            coor11(m*(p+1)+i,2) = R*sin((5*pi/4) + (i-1)*(pi/2)/p) + (d2/2);
            
        end


        for i = 1:m-1
            
            for j = 1:p+1
                
                dx = (coor11(m*(p+1)+j,1) - coor11(j,1))/m;
                dy = (coor11(m*(p+1)+j,2) - coor11(j,2))/m;
                
                coor11(i*(p+1)+j,1) = coor11(((i-1)*(p+1))+j,1) + dx;
                coor11(i*(p+1)+j,2) = coor11(((i-1)*(p+1))+j,2) + dy;
                
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 2  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        coor22 = zeros((p+1)*(m+1), app.PD);
        
        for i = 1:p+1
            
            coor22(i,1) = q(3,1) + (i-1)*a;
            coor22(i,2) = q(3,2);
            
        end
        
        for i = 1:p+1
            
            coor22(m*(p+1)+i,1) = R*cos((3*pi/4) - (i-1)*(pi/2)/p) + (d1/2);
            coor22(m*(p+1)+i,2) = R*sin((3*pi/4) - (i-1)*(pi/2)/p) + (d2/2);
            
        end
        
        for i = 1:m-1
            
            for j = 1:p+1
                
                dx = (coor22(m*(p+1)+j,1) - coor22(j,1))/m;
                dy = (coor22(m*(p+1)+j,2) - coor22(j,2))/m;
                
                coor22(i*(p+1)+j,1) = coor22(((i-1)*(p+1))+j,1) + dx;
                coor22(i*(p+1)+j,2) = coor22(((i-1)*(p+1))+j,2) + dy;
                
            end
            
        end
                
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 3  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        coor33 = zeros((p-1)*(m+1), app.PD);
        
        for i = 1:p-1
            
            coor33(i,1) = q(1,1);
            coor33(i,2) = q(1,2) + (i)*b;
            
        end
        
        for i = 1:p-1
            
            coor33(m*(p-1)+i,1) = R*cos((5*pi/4) - (i)*(pi/2)/p) + (d1/2);
            coor33(m*(p-1)+i,2) = R*sin((5*pi/4) - (i)*(pi/2)/p) + (d2/2);
            
        end
        
        for i = 1:m-1
            
            for j = 1:p-1
                
                dx = (coor33(m*(p-1)+j,1) - coor33(j,1))/m;
                dy = (coor33(m*(p-1)+j,2) - coor33(j,2))/m;
                
                coor33(i*(p-1)+j,1) = coor33(((i-1)*(p-1))+j,1) + dx;
                coor33(i*(p-1)+j,2) = coor33(((i-1)*(p-1))+j,2) + dy;
                
            end
            
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Region 4  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        coor44 = zeros((p-1)*(m+1), app.PD);
        
        for i = 1:p-1
            
            coor44(i,1) = q(2,1);
            coor44(i,2) = q(2,2) + (i)*b;
            
        end
        
        for i = 1:p-1
            
            coor44(m*(p-1)+i,1) = R*cos((7*pi/4) + (i)*(pi/2)/p) + (d1/2);
            coor44(m*(p-1)+i,2) = R*sin((7*pi/4) + (i)*(pi/2)/p) + (d2/2);
            
        end
        
        for i = 1:m-1
            
            for j = 1:p-1
                
                dx = (coor44(m*(p-1)+j,1) - coor44(j,1))/m;
                dy = (coor44(m*(p-1)+j,2) - coor44(j,2))/m;
                
                coor44(i*(p-1)+j,1) = coor44(((i-1)*(p-1))+j,1) + dx;
                coor44(i*(p-1)+j,2) = coor44(((i-1)*(p-1))+j,2) + dy;
                
            end
            
        end
        
        
        for i = 1:m+1
            
            app.NL((i-1)*4*p+1:(i)*4*p,:) = [coor11((i-1)*(p+1)+1:(i)*(p+1),:);
                                         coor44((i-1)*(p-1)+1:(i)*(p-1),:);
                                         flipud(coor22((i-1)*(p+1)+1:(i)*(p+1),:));
                                         flipud(coor33((i-1)*(p-1)+1:(i)*(p-1),:))];
            
        end
        
        for i = 1:app.NoN
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
            text(app.UIAxes, app.NL(i,1),app.NL(i,2), num2str(i), 'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
        end
        
        axis(app.UIAxes,"equal")
        
        
        app.EL = [];
        Node_Multiplier = p * 4;
        CriticalNodes = [];
    
        for j = 1:(m+1)
            CriticalNodes(size(CriticalNodes,1)+1,:) = [0];
            CriticalNodes(size(CriticalNodes,1),:) = [(j*Node_Multiplier)];
        end
    
        CriticalNodes = transpose(CriticalNodes);
        
         for i = 1:size(app.NL,1)
            for j = 1:(m+1)
                if i == j*Node_Multiplier
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [i-Node_Multiplier+1 i];
                end
            end
        end
    
        for i = 1:size(app.NL,1)-1
            app.EL(size(app.EL,1)+1,:) = [0 0];
            app.EL(size(app.EL,1),:) = [i i+1];
        end
    
        for k = 1:size(app.NL,1) - Node_Multiplier
            app.EL(size(app.EL,1)+1,:) = [0 0];
            app.EL(size(app.EL,1),:) = [k k+Node_Multiplier];
        end
    
        for a = 1:(size(app.NL,1)-Node_Multiplier-1)  
            if ismember(a,CriticalNodes)
            else
                app.EL(size(app.EL,1)+1,:) = [0 0];
                app.EL(size(app.EL,1),:) = [a a+Node_Multiplier+1];
            end
        end
    
        for c = 1:(size(app.NL,1)-Node_Multiplier)
            for d = 0:(m-1)
                if c == (d*Node_Multiplier)+1
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [c (c+(2*Node_Multiplier)-1)];
                else
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [c c+Node_Multiplier-1];
                end
            end
        end
        app.EL = unique(app.EL,"rows");
    
        for i=1 : size(app.EL,1)
            H = plot(app.UIAxes, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
            H.Color(4) = 0.2;
        
        end

        app.ofNodesEditField.Value = size(app.NL,1);
        app.ofElementsEditField.Value = size(app.EL,1);
    end


    function Void_Square_Mesher(app) %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        cla(app.UIAxes)

        app.NodeSize = 11;

        d1 = app.square_a;
        d2 = app.square_b;
        p = app.square_c;
        m = app.square_d;
        c = app.square_e;


        q = [0 0; d1 0; 0 d2; d1 d2];
        
        app.PD = 2;
        
        app.NoN = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
        
        app.NoE = 4*p*m;
        
        NPE = 4;
        
        app.NL = zeros(app.NoN, app.PD);
        
        a = d1/p;
        b = d2/p;
        %region 1
        
        coor11 = zeros((p+1)*(m+1), app.PD);
        
        for i = 1:p+1
            
            coor11(i,1) = q(1,1) + (i-1)*a;
            coor11(i,2) = q(1,2);
            
        end
        
        for i = 1:p+1
            
            coor11(m*(p+1)+i,1) = ((d1-c)/2)+(i-1)*(c/p);
            coor11(m*(p+1)+i,2) = ((d2-c)/2);
            
        end
        for i = 1:m-1
            
            for j = 1:p+1
                
                dx = (coor11(m*(p+1)+j,1) - coor11(j,1))/m;
                dy = (coor11(m*(p+1)+j,2) - coor11(j,2))/m;
                
                coor11(i*(p+1)+j,1) = coor11(((i-1)*(p+1))+j,1) + dx;
                coor11(i*(p+1)+j,2) = coor11(((i-1)*(p+1))+j,2) + dy;
                
            end
            
        end


        axis(app.UIAxes,"equal")
        
        
        %region 2
        
        coor22 = zeros((p+1)*(m+1), app.PD);
        
        for i = 1:p+1
            
            coor22(i,1) = q(3,1) + (i-1)*a;
            coor22(i,2) = q(3,2);
            
        end
        
        for i = 1:p+1
            
            coor22(m*(p+1)+i,1) = ((d1-c)/2)+(i-1)*(c/p);
            coor22(m*(p+1)+i,2) = ((d2+c)/2);
            
        end
        
        for i = 1:m-1
            
            for j = 1:p+1
                
                dx = (coor22(m*(p+1)+j,1) - coor22(j,1))/m;
                dy = (coor22(m*(p+1)+j,2) - coor22(j,2))/m;
                
                coor22(i*(p+1)+j,1) = coor22(((i-1)*(p+1))+j,1) + dx;
                coor22(i*(p+1)+j,2) = coor22(((i-1)*(p+1))+j,2) + dy;
                
            end
            
        end
        
        
        axis(app.UIAxes,"equal")
        %Region 3
        
        coor33 = zeros((p-1)*(m+1), app.PD);
        
        for i = 1:p-1
            
            coor33(i,1) = q(1,1);
            coor33(i,2) = q(1,2) + (i)*b;
            
        end
        
        for i = 1:p-1
            
            coor33(m*(p-1)+i,1) = (d1-c)/2;
            coor33(m*(p-1)+i,2) = ((d2-c)/2)+(i)*(c/p);
            
        end
        
        for i = 1:m-1
            
            for j = 1:p-1
                
                dx = (coor33(m*(p-1)+j,1) - coor33(j,1))/m;
                dy = (coor33(m*(p-1)+j,2) - coor33(j,2))/m;
                
                coor33(i*(p-1)+j,1) = coor33(((i-1)*(p-1))+j,1) + dx;
                coor33(i*(p-1)+j,2) = coor33(((i-1)*(p-1))+j,2) + dy;
                
            end
        
            
        end
        
        
        axis(app.UIAxes,"equal")
        
        
        %region4


        coor44 = zeros((p-1)*(m+1), app.PD);
        
        for i = 1:p-1
            
            coor44(i,1) = q(2,1);
            coor44(i,2) = q(2,2) + (i)*b;
            
        end
        
        for i = 1:p-1
            
            coor44(m*(p-1)+i,1) = (d1+c)/2;
            coor44(m*(p-1)+i,2) = ((d2-c)/2)+(i)*(c/p);
            
        end
        
        for i = 1:m-1
            
            for j = 1:p-1
                
                dx = (coor44(m*(p-1)+j,1) - coor44(j,1))/m;
                dy = (coor44(m*(p-1)+j,2) - coor44(j,2))/m;
                
                coor44(i*(p-1)+j,1) = coor44(((i-1)*(p-1))+j,1) + dx;
                coor44(i*(p-1)+j,2) = coor44(((i-1)*(p-1))+j,2) + dy;
                
            end
            
        end


        axis(app.UIAxes,"equal")

        for i = 1:m+1
            
            app.NL((i-1)*4*p+1:(i)*4*p,:) = [coor11((i-1)*(p+1)+1:(i)*(p+1),:);
                                         coor44((i-1)*(p-1)+1:(i)*(p-1),:);
                                         flipud(coor22((i-1)*(p+1)+1:(i)*(p+1),:));
                                         flipud(coor33((i-1)*(p-1)+1:(i)*(p-1),:))];
            
        end
        for i = 1:app.NoN
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
            text(app.UIAxes, app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
        end
        
        axis(app.UIAxes,"equal")
        
        
        app.EL = [];
        Node_Multiplier = p * 4;
        CriticalNodes = [];
    
        for j = 1:(m+1)
            CriticalNodes(size(CriticalNodes,1)+1,:) = [0];
            CriticalNodes(size(CriticalNodes,1),:) = [(j*Node_Multiplier)];
        end
    
        CriticalNodes = transpose(CriticalNodes);
        
         for i = 1:size(app.NL,1)
            for j = 1:(m+1)
                if i == j*Node_Multiplier
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [i-Node_Multiplier+1 i];
                end
            end
        end
    
        for i = 1:size(app.NL,1)-1
            app.EL(size(app.EL,1)+1,:) = [0 0];
            app.EL(size(app.EL,1),:) = [i i+1];
        end
    
        for k = 1:size(app.NL,1) - Node_Multiplier
            app.EL(size(app.EL,1)+1,:) = [0 0];
            app.EL(size(app.EL,1),:) = [k k+Node_Multiplier];
        end
    
        for a = 1:(size(app.NL,1)-Node_Multiplier-1)  
            if ismember(a,CriticalNodes)
            else
                app.EL(size(app.EL,1)+1,:) = [0 0];
                app.EL(size(app.EL,1),:) = [a a+Node_Multiplier+1];
            end
        end
    
        for c = 1:(size(app.NL,1)-Node_Multiplier)
            for d = 0:(m-1)
                if c == (d*Node_Multiplier)+1
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [c (c+(2*Node_Multiplier)-1)];
                else
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [c c+Node_Multiplier-1];
                end
            end
        end
        app.EL = unique(app.EL,"rows");
    
        for i=1 : size(app.EL,1)
            H = plot(app.UIAxes, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
            H.Color(4) = 0.2;
        
        end

        app.ofNodesEditField.Value = size(app.NL,1);
        app.ofElementsEditField.Value = size(app.EL,1);
    
    end


    function Void_Rhombus_Mesher(app)    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        cla(app.UIAxes)

        app.NodeSize = 11;

        d1 = app.rhombus_a;
        d2 = app.rhombus_b;
        p = app.rhombus_c;
        m = app.rhombus_d;
        c = app.rhombus_e;


        q = [0 0; d1 0; 0 d2; d1 d2];
    
        app.PD = 2;
        
        app.NoN = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
        
        app.NoE = 4*p*m;
        
        NPE = 4;
        
        app.NL = zeros(app.NoN, app.PD);
        
        a = d1/p;
        b = d2/p;
    %     %region 1
        
        coor11 = zeros((p+1)*(m+1), app.PD);
    
        for i = 1:p+1
            
            coor11(i,1) = q(1,1) + (i-1)*a;
            coor11(i,2) = q(1,2);
            
        end
        g=1;
        for i = 1:p+1
            
            if ((d1-(c/sqrt(2)))/2)+(i-1)*(c/p) <= d1/2
                coor11(m*(p+1)+i,1) = ((d1-(c/sqrt(2)))/2)+(i-1)*(c/p);
                coor11(m*(p+1)+i,2) = ((d2-(c/sqrt(2)))/2)-(i-1)*(c/p);
                g=g+1;
            else
                coor11(m*(p+1)+i,1) = 1.02*((d1-(c/sqrt(2)))/2)+(i-1)*(c/p);
                coor11(m*(p+1)+i,2) = 1.05*((d2-(2*c/sqrt(2)))/2)+(i-1-g)*(c/p);
            end
        end
        for i = 1:m-1
            
            for j = 1:p+1
                
                dx = (coor11(m*(p+1)+j,1) - coor11(j,1))/m;
                dy = (coor11(m*(p+1)+j,2) - coor11(j,2))/m;
                
                coor11(i*(p+1)+j,1) = coor11(((i-1)*(p+1))+j,1) + dx;
                coor11(i*(p+1)+j,2) = coor11(((i-1)*(p+1))+j,2) + dy;
                
            end
            
        end
        
        for i = 1:(p+1)*(m+1)
            hold(app.UIAxes,'on');
            plot(app.UIAxes,coor11(i,1),coor11(i,2),'o','MarkerSize',10, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
            text(app.UIAxes,coor11(i,1),coor11(i,2), num2str(i), 'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
        end
        
        axis(app.UIAxes,"equal")
        
        
    %     %region 2
        
        coor22 = zeros((p+1)*(m+1), app.PD);
    
        for i = 1:p+1
            
            coor22(i,1) = q(3,1) + (i-1)*a;
            coor22(i,2) = q(3,2);
            
        end
        
    alp=1;
        for i = 1:p+1
            
            if ((d1-(c/sqrt(2)))/2)+(i-1)*(c/p) <= d1/2
                coor22(m*(p+1)+i,1) = ((d1-(c/sqrt(2)))/2)+(i-1)*(c/p);
                coor22(m*(p+1)+i,2) = ((d2+(c/sqrt(2)))/2)+(i-1)*(c/p);
                alp=alp+1;
            else
                coor22(m*(p+1)+i,1) = 1.03*((d1-(c/sqrt(2)))/2)+(i-1)*(c/p);
                coor22(m*(p+1)+i,2) = 0.95*((d2+(2*c/sqrt(2)))/2)-(i-1-alp)*(c/p);
            end
        end
        
        for i = 1:m-1
            
            for j = 1:p+1
                
                dx = (coor22(m*(p+1)+j,1) - coor22(j,1))/m;
                dy = (coor22(m*(p+1)+j,2) - coor22(j,2))/m;
                
                coor22(i*(p+1)+j,1) = coor22(((i-1)*(p+1))+j,1) + dx;
                coor22(i*(p+1)+j,2) = coor22(((i-1)*(p+1))+j,2) + dy;
                
            end
            
        end
    %     for i = 1:(p+1)*(m+1)
    %         hold on;
    %         plot(coor22(i,1),coor22(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
    %         text(coor22(i,1),coor22(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
    %     end
    %     
    %  axis equal
        
     
     %Region 3
        
        coor33 = zeros((p-1)*(m+1), app.PD);
    
        for i = 1:p-1
            
            coor33(i,1) = q(1,1);
            coor33(i,2) = q(1,2) + (i)*b;
            
        end
    
    fatih=1;
        for i = 1:p-1
            
            if ((d2-(c/sqrt(2)))/2)+(i-1)*(c/p) <= d2/2
                coor33(m*(p-1)+i,1) = ((d1-(c/sqrt(2)))/2)-c/(1.5*p)-(i-1)*(c/(1.5*p));
                coor33(m*(p-1)+i,2) = ((d2-(c/sqrt(2)))/2)+c/(1.5*p)+(i-1)*(c/(1.5*p));
                fatih=fatih+1;
            else
                coor33(m*(p-1)+i,1) = ((d1-(c/sqrt(2)))/2)+0.01+(i-1-fatih)*(c/p);
                coor33(m*(p-1)+i,2) = ((d2-(c/sqrt(2)))/2)+(i-1)*(c/p);
                
            end
        end
    
        
        for i = 1:m-1
            
            for j = 1:p-1
                
                dx = (coor33(m*(p-1)+j,1) - coor33(j,1))/m;
                dy = (coor33(m*(p-1)+j,2) - coor33(j,2))/m;
                
                coor33(i*(p-1)+j,1) = coor33(((i-1)*(p-1))+j,1) + dx;
                coor33(i*(p-1)+j,2) = coor33(((i-1)*(p-1))+j,2) + dy;
                
            end
        
            
        end
    
    
    %     for i = 1:size(coor33)
    %         hold on;
    %         plot(coor33(i,1),coor33(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
    %         text(coor33(i,1),coor33(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
    %     end
    %     
    %     axis equal
        
        
        %region4


        coor44 = zeros((p-1)*(m+1), app.PD);
    
        for i = 1:p-1
            
            coor44(i,1) = q(2,1);
            coor44(i,2) = q(2,2) + (i)*b;
            
        end
        
    terim=1;
        for i = 1:p-1
            
            if ((d2-(c/sqrt(2)))/2)+(i-1)*(c/p) <= d2/2
                coor44(m*(p-1)+i,1) = ((d1+(2*c/sqrt(2)))/2)+(c/(1.5*p))+(i-1)*(c/(1.5*p));
                coor44(m*(p-1)+i,2) = ((d2-(c/sqrt(2)))/2)+(c/(1.5*p))+(i-1)*(c/(1.5*p));
                terim=terim+1;
            else
                coor44(m*(p-1)+i,1) = ((d1+(2*c/sqrt(2)))/2)-0.01-(i-1-terim)*(c/p);
                coor44(m*(p-1)+i,2) = ((d2-(c/sqrt(2)))/2)+(i-1)*(c/p);
                
            end
        end
        
        for i = 1:m-1
            
            for j = 1:p-1
                
                dx = (coor44(m*(p-1)+j,1) - coor44(j,1))/m;
                dy = (coor44(m*(p-1)+j,2) - coor44(j,2))/m;
                
                coor44(i*(p-1)+j,1) = coor44(((i-1)*(p-1))+j,1) + dx;
                coor44(i*(p-1)+j,2) = coor44(((i-1)*(p-1))+j,2) + dy;
                
            end
            
        end
    %     for i = 1:(p+1)*(m+1)
    %         hold on;
    %         plot(coor11(i,1),coor11(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
    %         text(coor11(i,1),coor11(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
    %     end
    %     axis equal
    %     for i = 1:(p+1)*(m+1)
    %         hold on;
    %         plot(coor22(i,1),coor22(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
    %         text(coor22(i,1),coor22(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
    %     end
    %     axis equal
    %     for i = 1:(p-1)*(m+1)
    %         hold on;
    %         plot(coor33(i,1),coor33(i,2),'o','MarkerSize',30, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
    %         text(coor33(i,1),coor33(i,2), num2str(i), 'Color','w','FontSize',20,'HorizontalAlignment','center')
    %     end
        axis(app.UIAxes,"equal")
        for i = 1:(p-1)*(m+1)
            hold(app.UIAxes,'on');
            plot(app.UIAxes,coor44(i,1),coor44(i,2),'o','MarkerSize',10, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
            text(app.UIAxes,coor44(i,1),coor44(i,2), num2str(i), 'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
        end
        
        axis(app.UIAxes,"equal")
        for i = 1:m+1
                    
            app.NL((i-1)*4*p+1:(i)*4*p,:) = [coor11((i-1)*(p+1)+1:(i)*(p+1),:);
                                         coor44((i-1)*(p-1)+1:(i)*(p-1),:);
                                         flipud(coor22((i-1)*(p+1)+1:(i)*(p+1),:));
                                         flipud(coor33((i-1)*(p-1)+1:(i)*(p-1),:))];
            
        end
        for i = 1:app.NoN
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.NL(i,1),app.NL(i,2),'o','MarkerSize',10,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
            text(app.UIAxes, app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',app.NodeSize,'HorizontalAlignment','center')
        end
    
        axis(app.UIAxes,"equal")


        app.EL = [];
        Node_Multiplier = p * 4;
        CriticalNodes = [];
    
        for j = 1:(m+1)
            CriticalNodes(size(CriticalNodes,1)+1,:) = [0];
            CriticalNodes(size(CriticalNodes,1),:) = [(j*Node_Multiplier)];
        end
    
        CriticalNodes = transpose(CriticalNodes);
        
         for i = 1:size(app.NL,1)
            for j = 1:(m+1)
                if i == j*Node_Multiplier
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [i-Node_Multiplier+1 i];
                end
            end
        end
    
        for i = 1:size(app.NL,1)-1
            app.EL(size(app.EL,1)+1,:) = [0 0];
            app.EL(size(app.EL,1),:) = [i i+1];
        end
    
        for k = 1:size(app.NL,1) - Node_Multiplier
            app.EL(size(app.EL,1)+1,:) = [0 0];
            app.EL(size(app.EL,1),:) = [k k+Node_Multiplier];
        end
    
        for a = 1:(size(app.NL,1)-Node_Multiplier-1)  
            if ismember(a,CriticalNodes)
            else
                app.EL(size(app.EL,1)+1,:) = [0 0];
                app.EL(size(app.EL,1),:) = [a a+Node_Multiplier+1];
            end
        end
    
        for c = 1:(size(app.NL,1)-Node_Multiplier)
            for d = 0:(m-1)
                if c == (d*Node_Multiplier)+1
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [c (c+(2*Node_Multiplier)-1)];
                else
                    app.EL(size(app.EL,1)+1,:) = [0 0];
                    app.EL(size(app.EL,1),:) = [c c+Node_Multiplier-1];
                end
            end
        end
        app.EL = unique(app.EL,"rows");
    
        for i=1 : size(app.EL,1)
            H = plot(app.UIAxes, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)] ,[app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
            H.Color(4) = 0.2;
        
        end

        app.ofNodesEditField.Value = size(app.NL,1);
        app.ofElementsEditField.Value = size(app.EL,1);
    
    end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeamPlotButton
        function BeamPlotButtonPushed(app, event)
            app.NoN = [];
            app.NoE = [];
            app.NL = [];
            app.EL = [];
    
            app.NoN2 = [];
            app.NoE2 = [];
            app.NL2 = [];
            app.EL2 = [];
            app.NPE2 = [];

            app.Uniform_a = app.Beam_aEditField.Value;  
        
            app.Uniform_b = app.Beam_bEditField.Value;
    
            app.Uniform_c = app.Beam_cEditField.Value;
        
            app.Uniform_d = app.Beam_dEditField.Value;
            
            if app.dimension == 1

                uniform_mesh_1D(app)

            elseif app.dimension == 2

                p = app.Uniform_c;
                m = app.Uniform_d;

                if isequal(app.element_type, 'D2TR3N')
                    app.NPE2 = 3;
                    app.NoE2 = p*m*2;
                elseif isequal(app.element_type, 'D2TR6N')
                    app.Uniform_c = app.Uniform_c*2;
                    app.Uniform_d = app.Uniform_d*2;
                    p = app.Uniform_c;
                    m = app.Uniform_d;
                    app.NPE2 = 6;
                    app.NoE2 = (p/2)*(m/2)*2;
                elseif isequal(app.element_type, 'D2QU8N')
                    app.Uniform_c = app.Uniform_c*2;
                    app.Uniform_d = app.Uniform_d*2;
                    p = app.Uniform_c;
                    m = app.Uniform_d;
                    app.NPE2 = 8;
                    app.NoE2 = (p/2)*(m/2);
                elseif isequal(app.element_type, 'D2QU9N')
                    app.Uniform_c = app.Uniform_c*2;
                    app.Uniform_d = app.Uniform_d*2;
                    p = app.Uniform_c;
                    m = app.Uniform_d;
                    app.NPE2 = 9;
                    app.NoE2 = (p/2)*(m/2);
                elseif isequal(app.element_type, 'D2QU4N')
                    app.NPE2 = 4;
                    app.NoE2 = p*m;
                end

                p = app.Uniform_c;
                m = app.Uniform_d;

                [app.NL2, app.EL2] = uniform_mesh(app.Uniform_a, app.Uniform_b, app.Uniform_c, app.Uniform_d, app.element_type);
                app.NoN2 = size(app.NL2,1);
 
                
                switch app.element_type
                
                    case 'D2TR3N'
                        cla(app.UIAxes)
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,1),1), app.NL2(app.EL2 (i,2),1)], [app.NL2(app.EL2 (i,1),2), app.NL2(app.EL2 (i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,2),1), app.NL2(app.EL2 (i,3),1)], [app.NL2(app.EL2 (i,2),2), app.NL2(app.EL2 (i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,3),1), app.NL2(app.EL2 (i,1),1)], [app.NL2(app.EL2 (i,3),2), app.NL2(app.EL2 (i,1),2)],'m');
                            x = (app.NL2(app.EL2 (i,1),1) + app.NL2(app.EL2 (i,2),1) + app.NL2(app.EL2 (i,3),1))/3;
                            y = (app.NL2(app.EL2 (i,1),2) + app.NL2(app.EL2 (i,2),2) + app.NL2(app.EL2 (i,3),2))/3;
                            
                        end
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2TR6N'
                        cla(app.UIAxes)
                        
                        for i = 1:app.NoE2
                
                            hold(app.UIAxes,"on");
                
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,1),1), app.NL2(app.EL2 (i,4),1)], [app.NL2(app.EL2 (i,1),2), app.NL2(app.EL2 (i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,4),1), app.NL2(app.EL2 (i,2),1)], [app.NL2(app.EL2 (i,4),2), app.NL2(app.EL2 (i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,2),1), app.NL2(app.EL2 (i,5),1)], [app.NL2(app.EL2 (i,2),2), app.NL2(app.EL2 (i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,5),1), app.NL2(app.EL2 (i,3),1)], [app.NL2(app.EL2 (i,5),2), app.NL2(app.EL2 (i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,3),1), app.NL2(app.EL2 (i,6),1)], [app.NL2(app.EL2 (i,3),2), app.NL2(app.EL2 (i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,6),1), app.NL2(app.EL2 (i,1),1)], [app.NL2(app.EL2 (i,6),2), app.NL2(app.EL2 (i,1),2)],'m');
                
                            x = (app.NL2(app.EL2 (i,6),1) + app.NL2(app.EL2 (i,2),1)+ app.NL2(app.EL2 (i,1),1) + app.NL2(app.EL2 (i,3),1)+ app.NL2(app.EL2 (i,4),1) + app.NL2(app.EL2 (i,5),1))/6;
                            y = (app.NL2(app.EL2 (i,6),2) +app.NL2(app.EL2 (i,2),2)+ app.NL2(app.EL2 (i,1),2) + app.NL2(app.EL2 (i,3),2)+ app.NL2(app.EL2 (i,4),2) + app.NL2(app.EL2 (i,5),2))/6;
                
                            
                        end
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")


                    case 'D2QU4N'
                        cla(app.UIAxes)
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,1),1), app.NL2(app.EL2 (i,2),1)], [app.NL2(app.EL2 (i,1),2), app.NL2(app.EL2 (i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,2),1), app.NL2(app.EL2 (i,3),1)], [app.NL2(app.EL2 (i,2),2), app.NL2(app.EL2 (i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,3),1), app.NL2(app.EL2 (i,4),1)], [app.NL2(app.EL2 (i,3),2), app.NL2(app.EL2 (i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,4),1), app.NL2(app.EL2 (i,1),1)], [app.NL2(app.EL2 (i,4),2), app.NL2(app.EL2 (i,1),2)],'m');
                            x = (app.NL2(app.EL2 (i,1),1) + app.NL2(app.EL2 (i,2),1) + app.NL2(app.EL2 (i,3),1) + app.NL2(app.EL2 (i,4),1))/4;
                            y = (app.NL2(app.EL2 (i,1),2) + app.NL2(app.EL2 (i,2),2) + app.NL2(app.EL2 (i,3),2) + app.NL2(app.EL2 (i,4),2))/4;
                            
                        end
                        
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU9N'
                        cla(app.UIAxes)
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on")
                            
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,1),1), app.NL2(app.EL2 (i,5),1)], [app.NL2(app.EL2 (i,1),2), app.NL2(app.EL2 (i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,5),1), app.NL2(app.EL2 (i,2),1)], [app.NL2(app.EL2 (i,5),2), app.NL2(app.EL2 (i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,2),1), app.NL2(app.EL2 (i,6),1)], [app.NL2(app.EL2 (i,2),2), app.NL2(app.EL2 (i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,6),1), app.NL2(app.EL2 (i,3),1)], [app.NL2(app.EL2 (i,6),2), app.NL2(app.EL2 (i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,3),1), app.NL2(app.EL2 (i,7),1)], [app.NL2(app.EL2 (i,3),2), app.NL2(app.EL2 (i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,7),1), app.NL2(app.EL2 (i,4),1)], [app.NL2(app.EL2 (i,7),2), app.NL2(app.EL2 (i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,4),1), app.NL2(app.EL2 (i,8),1)], [app.NL2(app.EL2 (i,4),2), app.NL2(app.EL2 (i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,8),1), app.NL2(app.EL2 (i,1),1)], [app.NL2(app.EL2 (i,8),2), app.NL2(app.EL2 (i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2 (i,1),1) + app.NL2(app.EL2 (i,2),1) +app.NL2(app.EL2 (i,3),1) + app.NL2(app.EL2 (i,4),1))/4;
                            y = (app.NL2(app.EL2 (i,1),2) + app.NL2(app.EL2 (i,2),2) + app.NL2(app.EL2 (i,3),2) + app.NL2(app.EL2 (i,4),2))/4;
                            
                            
                        end
                        
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on")
                            
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU8N'
                        cla(app.UIAxes)
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on")
                            
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,1),1), app.NL2(app.EL2 (i,5),1)], [app.NL2(app.EL2 (i,1),2), app.NL2(app.EL2 (i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,5),1), app.NL2(app.EL2 (i,2),1)], [app.NL2(app.EL2 (i,5),2), app.NL2(app.EL2 (i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,2),1), app.NL2(app.EL2 (i,6),1)], [app.NL2(app.EL2 (i,2),2), app.NL2(app.EL2 (i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,6),1), app.NL2(app.EL2 (i,3),1)], [app.NL2(app.EL2 (i,6),2), app.NL2(app.EL2 (i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,3),1), app.NL2(app.EL2 (i,7),1)], [app.NL2(app.EL2 (i,3),2), app.NL2(app.EL2 (i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,7),1), app.NL2(app.EL2 (i,4),1)], [app.NL2(app.EL2 (i,7),2), app.NL2(app.EL2 (i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,4),1), app.NL2(app.EL2 (i,8),1)], [app.NL2(app.EL2 (i,4),2), app.NL2(app.EL2 (i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2 (i,8),1), app.NL2(app.EL2 (i,1),1)], [app.NL2(app.EL2 (i,8),2), app.NL2(app.EL2 (i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2 (i,1),1) + app.NL2(app.EL2 (i,2),1) + app.NL2(app.EL2 (i,3),1) + app.NL2(app.EL2 (i,4),1))/4;
                            y = (app.NL2(app.EL2 (i,1),2) + app.NL2(app.EL2 (i,2),2) + app.NL2(app.EL2 (i,3),2) +app.NL2(app.EL2 (i,4),2))/4;
                            
                            
                        end
                        
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on")
                            
                            if ismember(i,app.EL2)
                            
                                plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                                
                                text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                            end
                        end
                        
                        axis(app.UIAxes,"equal")
                        
                end
                
        
                app.ofNodesEditField.Value = size(app.NL2,1);
                app.ofElementsEditField.Value = size(app.EL2,1);

            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.DimensionSwitch = app.Switch.Value;
            switch app.DimensionSwitch
                case "2D"
                    app.dimension = 2;
                    app.PD = 2;

                case "1D"
                    app.dimension = 1;
                    app.PD = 1;
            end
            
        end

        % Value changed function: Beam_aEditField
        function Beam_aEditFieldValueChanged(app, event)
            app.Uniform_a = app.Beam_aEditField.Value;  
            app.d1 = app.Uniform_a;
        end

        % Value changed function: Beam_bEditField
        function Beam_bEditFieldValueChanged(app, event)
            app.Uniform_b = app.Beam_bEditField.Value;
            app.d2 = app.Uniform_b;
            
        end

        % Value changed function: Beam_cEditField
        function Beam_cEditFieldValueChanged(app, event)
            app.Uniform_c = app.Beam_cEditField.Value;
            app.p = app.Uniform_c;
        end

        % Value changed function: Beam_dEditField
        function Beam_dEditFieldValueChanged(app, event)
            app.Uniform_d = app.Beam_dEditField.Value;
            app.m = app.Uniform_d;
        end

        % Value changed function: Circle_aEditField
        function Circle_aEditFieldValueChanged(app, event)
            app.circle_a = app.Circle_aEditField.Value;
            app.d1 = app.circle_a;
        end

        % Value changed function: Circle_bEditField
        function Circle_bEditFieldValueChanged(app, event)
            app.circle_b = app.Circle_bEditField.Value;
            app.d2 = app.circle_b;
        end

        % Value changed function: Circle_cEditField
        function Circle_cEditFieldValueChanged(app, event)
            app.circle_c = app.Circle_cEditField.Value;
            app.p = app.circle_c;
        end

        % Value changed function: Circle_dEditField
        function Circle_dEditFieldValueChanged(app, event)
            app.circle_d = app.Circle_dEditField.Value;
            app.m = app.circle_d;
        end

        % Value changed function: Circle_rEditField
        function Circle_rEditFieldValueChanged(app, event)
            app.circle_r = app.Circle_rEditField.Value;
            
        end

        % Value changed function: Square_aEditField
        function Square_aEditFieldValueChanged(app, event)
            app.square_a = app.Square_aEditField.Value;
            app.d1 = app.square_a;
        end

        % Value changed function: Square_bEditField
        function Square_bEditFieldValueChanged(app, event)
            app.square_b = app.Square_bEditField.Value;
            app.d2 = app.square_b;
        end

        % Value changed function: Square_cEditField
        function Square_cEditFieldValueChanged(app, event)
            app.square_c = app.Square_cEditField.Value;
            app.p = app.square_c;
        end

        % Value changed function: Square_dEditField
        function Square_dEditFieldValueChanged(app, event)
            app.square_d = app.Square_dEditField.Value;
            app.m = app.square_d;
        end

        % Value changed function: Square_eEditField
        function Square_eEditFieldValueChanged(app, event)
            app.square_e = app.Square_eEditField.Value;
            
        end

        % Value changed function: Rhombus_aEditField
        function Rhombus_aEditFieldValueChanged(app, event)
            app.rhombus_a = app.Rhombus_aEditField.Value;
            app.d1 = app.rhombus_a;
            
        end

        % Value changed function: Rhombus_bEditField
        function Rhombus_bEditFieldValueChanged(app, event)
            app.rhombus_b = app.Rhombus_bEditField.Value;
            app.d2 = app.rhombus_b;
            
        end

        % Value changed function: Rhombus_cEditField
        function Rhombus_cEditFieldValueChanged(app, event)
            app.rhombus_c = app.Rhombus_cEditField.Value;
            app.p = app.rhombus_c;
        end

        % Value changed function: Rhombus_dEditField
        function Rhombus_dEditFieldValueChanged(app, event)
            app.rhombus_d = app.Rhombus_dEditField.Value;
            app.m = app.rhombus_d;
        end

        % Value changed function: Rhombus_eEditField
        function Rhombus_eEditFieldValueChanged(app, event)
            app.rhombus_e = app.Rhombus_eEditField.Value;
            
        end

        % Value changed function: ElementTypeDropDown
        function ElementTypeDropDownValueChanged(app, event)
            app.element_type = app.ElementTypeDropDown.Value;
            
        end

        % Value changed function: ofNodesEditField
        function ofNodesEditFieldValueChanged(app, event)
            value = app.ofNodesEditField.Value;
            
        end

        % Value changed function: ofElementsEditField
        function ofElementsEditFieldValueChanged(app, event)
            value = app.ofElementsEditField.Value;
            
        end

        % Button pushed function: CirclePlotButton
        function CirclePlotButtonPushed(app, event)
            app.NoN = [];
            app.NoE = [];
            app.NL = [];
            app.EL = [];
    
            app.NoN2 = [];
            app.NoE2 = [];
            app.NL2 = [];
            app.EL2 = [];
            app.NPE2 = [];

            app.circle_a = app.Circle_aEditField.Value;  
        
            app.circle_b = app.Circle_bEditField.Value;
    
            app.circle_c = app.Circle_cEditField.Value;
        
            app.circle_d = app.Circle_dEditField.Value;

            app.circle_r = app.Circle_rEditField.Value;
            
            if app.dimension == 1

                Void_Circle_Mesher_1D(app)

            elseif app.dimension == 2
                
                cla(app.UIAxes)
        
                
                p = app.circle_c;
                m = app.circle_d;


                if isequal(app.element_type, 'D2TR3N')
                
                    app.NPE2 = 3;
                    if app.inc
                
                        app.NoN2 = 2*(p+1)*(2*m+1) + 2*(p-1)*(2*m+1) + (p-1)^2;
                        app.NoE2 = 16*p*m+(p^2)*2;
                    
                    else
                        app.NoN2 = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                        app.NoE2 = p*m*8;
                    end
                
                
                elseif isequal(app.element_type, 'D2TR6N')
                
                    app.NPE2 = 6;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 16*p*m+2*(p^2);
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 8*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU8N')
                
                    app.NPE2 = 8;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 8*p*m+p^2;
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 4*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU9N')
                
                    app.NPE2 = 9;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 8*p*m+p^2;
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 4*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU4N')
                
                    app.NPE2 = 4;
                    if app.inc
                
                        app.NoN2 = 2*(p+1)*(2*m+1) + 2*(p-1)*(2*m+1) + (p-1)^2;
                        app.NoE2 = 8*p*m+(p^2);
                    
                    else
                        app.NoN2 = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                        app.NoE2 = 4*p*m;
                    end
                
                end
                
                [app.NL2, app.EL2] = void_mesh(app.circle_a, app.circle_b, app.circle_c, app.circle_d, app.circle_r, app.element_type, app.inc);
                
                
                switch app.element_type
                
                    case 'D2TR3N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,1),2)],'m');
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1))/3;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2))/3;
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2TR6N'
                        
                        for i = 1:app.NoE2
                
                            hold(app.UIAxes,"on") ;
                
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,1),2)],'m');
                
                            x = (app.NL2(app.EL2(i,6),1) + app.NL2(app.EL2(i,2),1)+ app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,3),1)+ app.NL2(app.EL2(i,4),1) + app.NL2(app.EL2(i,5),1))/6;
                            y = (app.NL2(app.EL2(i,6),2) + app.NL2(app.EL2(i,2),2)+ app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,3),2)+ app.NL2(app.EL2(i,4),2) + app.NL2(app.EL2(i,5),2))/6;
                
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        app.NoN2 = size(app.NL2,1);
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")


                    case 'D2QU4N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,1),2)],'m');
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU9N'
                        app.NoN2 = size(app.NL2,1);
                
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,7),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,7),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,7),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,8),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,8),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,8),2), app.NL2(app.EL2(i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'r', 'FontSize', 12, 'HorizontalAlignment','center')
                        end


                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU8N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,7),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,7),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,7),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,8),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,8),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,8),2), app.NL2(app.EL2(i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'r', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        app.NoN2 = size(app.NL2,1);
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            if ismember(i,app.EL2)
                            
                                plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                                
                                text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                            end
                        end
                        
                        axis(app.UIAxes,"equal")
                        
                end
                app.ofNodesEditField.Value = size(app.NL2,1);
                app.ofElementsEditField.Value = size(app.EL2,1);

            end
        end

        % Button pushed function: SquarePlotButton
        function SquarePlotButtonPushed(app, event)
            if app.dimension == 1
                
                Void_Square_Mesher(app)

            elseif app.dimension == 2

                cla(app.UIAxes)
        
                d1 = app.square_a;
                d2 = app.square_b;
                p = app.square_c;
                m = app.square_d;
                c = app.square_e;


                if isequal(app.element_type, 'D2TR3N')
                
                    app.NPE2 = 3;
                    if app.inc
                
                        app.NoN2 = 2*(p+1)*(2*m+1) + 2*(p-1)*(2*m+1) + (p-1)^2;
                        app.NoE2 = 16*p*m+(p^2)*2;
                    
                    else
                        app.NoN2 = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                        app.NoE2 = p*m*8;
                    end
                
                
                elseif isequal(app.element_type, 'D2TR6N')
                
                    app.NPE2 = 6;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 16*p*m+2*(p^2);
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 8*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU8N')
                
                    app.NPE2 = 8;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 8*p*m+p^2;
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 4*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU9N')
                
                    app.NPE2 = 9;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 8*p*m+p^2;
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 4*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU4N')
                
                    app.NPE2 = 4;
                    if app.inc
                
                        app.NoN2 = 2*(p+1)*(2*m+1) + 2*(p-1)*(2*m+1) + (p-1)^2;
                        app.NoE2 = 8*p*m+(p^2);
                    
                    else
                        app.NoN2 = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                        app.NoE2 = 4*p*m;
                    end
                
                end
                
                [app.NL2, app.EL2] = void_square_mesh(app.square_a, app.square_b, app.square_c, app.square_d, app.square_e, app.element_type);
                
                
                switch app.element_type
                
                    case 'D2TR3N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,1),2)],'m');
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1))/3;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2))/3;
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2TR6N'
                        
                        for i = 1:app.NoE2
                
                            hold(app.UIAxes,"on") ;
                
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,1),2)],'m');
                
                            x = (app.NL2(app.EL2(i,6),1) + app.NL2(app.EL2(i,2),1)+ app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,3),1)+ app.NL2(app.EL2(i,4),1) + app.NL2(app.EL2(i,5),1))/6;
                            y = (app.NL2(app.EL2(i,6),2) + app.NL2(app.EL2(i,2),2)+ app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,3),2)+ app.NL2(app.EL2(i,4),2) + app.NL2(app.EL2(i,5),2))/6;
                
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        app.NoN2 = size(app.NL2,1);
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")


                    case 'D2QU4N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,1),2)],'m');
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU9N'
                        app.NoN2 = size(app.NL2,1);
                
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,7),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,7),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,7),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,8),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,8),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,8),2), app.NL2(app.EL2(i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'r', 'FontSize', 12, 'HorizontalAlignment','center')
                        end


                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU8N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,7),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,7),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,7),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,8),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,8),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,8),2), app.NL2(app.EL2(i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'r', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        app.NoN2 = size(app.NL2,1);
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            if ismember(i,app.EL2)
                            
                                plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                                
                                text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                            end
                        end
                        
                        axis(app.UIAxes,"equal")
                        
                end
                app.ofNodesEditField.Value = size(app.NL2,1);
                app.ofElementsEditField.Value = size(app.EL2,1);

            end
        end

        % Button pushed function: RhombusPlotButton
        function RhombusPlotButtonPushed(app, event)
            if app.dimension == 1
                
                Void_Rhombus_Mesher(app)

            elseif app.dimension == 2

                cla(app.UIAxes)


                d1 = app.rhombus_a;
                d2 = app.rhombus_b;
                p = app.rhombus_c;
                m = app.rhombus_d;
                c = app.rhombus_e;


                if isequal(app.element_type, 'D2TR3N')
                
                    app.NPE2 = 3;
                    if app.inc
                
                        app.NoN2 = 2*(p+1)*(2*m+1) + 2*(p-1)*(2*m+1) + (p-1)^2;
                        app.NoE2 = 16*p*m+(p^2)*2;
                    
                    else
                        app.NoN2 = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                        app.NoE2 = p*m*8;
                    end
                
                
                elseif isequal(app.element_type, 'D2TR6N')
                
                    app.NPE2 = 6;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 16*p*m+2*(p^2);
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 8*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU8N')
                
                    app.NPE2 = 8;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 8*p*m+p^2;
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 4*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU9N')
                
                    app.NPE2 = 9;
                    if app.inc
                
                        app.NoN2 = 32*p*m + (2*p-1)^2;
                        app.NoE2 = 8*p*m+p^2;
                    
                    else
                        app.NoN2 = (2*(p+1)*(m+1) + 2*(p-1)*(m+1))*2 + 8 * p * m;
                        app.NoE2 = 4*p*m;
                    end
                
                
                elseif isequal(app.element_type, 'D2QU4N')
                
                    app.NPE2 = 4;
                    if app.inc
                
                        app.NoN2 = 2*(p+1)*(2*m+1) + 2*(p-1)*(2*m+1) + (p-1)^2;
                        app.NoE2 = 8*p*m+(p^2);
                    
                    else
                        app.NoN2 = 2*(p+1)*(m+1) + 2*(p-1)*(m+1);
                        app.NoE2 = 4*p*m;
                    end
                
                end
                
                
                [app.NL2, app.EL2] = void_rhombus_mesh(app.rhombus_a, app.rhombus_b, app.rhombus_c, app.rhombus_d, app.rhombus_e, app.element_type);
                
                
                switch app.element_type
                
                    case 'D2TR3N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,1),2)],'m');
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1))/3;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2))/3;
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2TR6N'
                        
                        for i = 1:app.NoE2
                
                            hold(app.UIAxes,"on") ;
                
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,1),2)],'m');
                
                            x = (app.NL2(app.EL2(i,6),1) + app.NL2(app.EL2(i,2),1)+ app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,3),1)+ app.NL2(app.EL2(i,4),1) + app.NL2(app.EL2(i,5),1))/6;
                            y = (app.NL2(app.EL2(i,6),2) + app.NL2(app.EL2(i,2),2)+ app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,3),2)+ app.NL2(app.EL2(i,4),2) + app.NL2(app.EL2(i,5),2))/6;
                
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        app.NoN2 = size(app.NL2,1);
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2), num2str(i), 'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")


                    case 'D2QU4N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,1),2)],'m');
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU9N'
                        app.NoN2 = size(app.NL2,1);
                
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                            
                            text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                        end
                        
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,7),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,7),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,7),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,8),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,8),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,8),2), app.NL2(app.EL2(i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'r', 'FontSize', 12, 'HorizontalAlignment','center')
                        end


                        axis(app.UIAxes,"equal")
                
                
                    case 'D2QU8N'
                        
                        for i = 1:app.NoE2
                            hold(app.UIAxes,"on") ;
                            
                            plot(app.UIAxes,[app.NL2(app.EL2(i,1),1), app.NL2(app.EL2(i,5),1)], [app.NL2(app.EL2(i,1),2), app.NL2(app.EL2(i,5),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,5),1), app.NL2(app.EL2(i,2),1)], [app.NL2(app.EL2(i,5),2), app.NL2(app.EL2(i,2),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,2),1), app.NL2(app.EL2(i,6),1)], [app.NL2(app.EL2(i,2),2), app.NL2(app.EL2(i,6),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,6),1), app.NL2(app.EL2(i,3),1)], [app.NL2(app.EL2(i,6),2), app.NL2(app.EL2(i,3),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,3),1), app.NL2(app.EL2(i,7),1)], [app.NL2(app.EL2(i,3),2), app.NL2(app.EL2(i,7),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,7),1), app.NL2(app.EL2(i,4),1)], [app.NL2(app.EL2(i,7),2), app.NL2(app.EL2(i,4),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,4),1), app.NL2(app.EL2(i,8),1)], [app.NL2(app.EL2(i,4),2), app.NL2(app.EL2(i,8),2)],'m');
                            plot(app.UIAxes,[app.NL2(app.EL2(i,8),1), app.NL2(app.EL2(i,1),1)], [app.NL2(app.EL2(i,8),2), app.NL2(app.EL2(i,1),2)],'m');
                            
                            x = (app.NL2(app.EL2(i,1),1) + app.NL2(app.EL2(i,2),1) + app.NL2(app.EL2(i,3),1) + app.NL2(app.EL2(i,4),1))/4;
                            y = (app.NL2(app.EL2(i,1),2) + app.NL2(app.EL2(i,2),2) + app.NL2(app.EL2(i,3),2) + app.NL2(app.EL2(i,4),2))/4;
                            
                            %text(app.UIAxes,x,y,num2str(i), 'Color', 'r', 'FontSize', 12, 'HorizontalAlignment','center')
                        end
                        
                        app.NoN2 = size(app.NL2,1);
                        
                        for i = 1:app.NoN2
                            hold(app.UIAxes,"on") ;
                            if ismember(i,app.EL2)
                            
                                plot(app.UIAxes,app.NL2(i,1),app.NL2(i,2),'o','MarkerSize',8,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
                                
                                text(app.UIAxes,app.NL2(i,1),app.NL2(i,2),num2str(i),'Color','w','FontSize',8,'HorizontalAlignment','center')
                            end
                        end
                        
                        axis(app.UIAxes,"equal")
                        
                end
                app.ofNodesEditField.Value = size(app.NL2,1);
                app.ofElementsEditField.Value = size(app.EL2,1);

            end
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            app.inculisonSwitch = app.Switch_3.Value;
            
            switch app.inculisonSwitch
                case "Void"
                    app.inc = 0;

                case "Inclusion"
                    app.inc = 1;
            end
            
        end

        % Value changed function: EEditField
        function EEditFieldValueChanged(app, event)
            app.EV(1,1) = app.EEditField.Value;
            
        end

        % Value changed function: EEditField_Inc
        function EEditField_IncValueChanged(app, event)
            app.EV(1,2) = app.EEditField_Inc.Value;
            
        end

        % Value changed function: vEditField
        function vEditFieldValueChanged(app, event)
            app.EV(1,2) = app.vEditField.Value;
            
        end

        % Value changed function: vEditField_Inc
        function vEditField_IncValueChanged(app, event)
             app.EV(2,2) = app.vEditField_Inc.Value;
            
        end

        % Value changed function: DeformationMagnifierEditField
        function DeformationMagnifierEditFieldValueChanged(app, event)
            app.magnitude = app.DeformationMagnifierEditField.Value;
            
        end

        % Button pushed function: BeamExtension
        function BeamExtensionButtonPushed(app, event)
            if app.dimension == 1

                app.ENL = zeros(size(app.NL,1),12);
                app.ENL(:,1:2) = app.NL;
                app.ENL(:,3:4) = ones(size(app.NL,1),2);
                app.ENL((app.Uniform_c/2)+1,3) = [-1];
                app.ENL((app.Uniform_c/2)+1+(5*(app.Uniform_c+1)),3) = [-1];
            for i = 1:app.Uniform_c
                app.ENL((i*(app.Uniform_c+1)),11) = [app.Force13/(app.Uniform_c+1)];
                app.ENL(((i-1)*(app.Uniform_c+1))+1,11) = [500/(-app.Uniform_c-1)];
            end

            else
            
                app.BCType = "Extension";
            end
        end

        % Button pushed function: BeamExpansion
        function BeamExpansionButtonPushed(app, event)
           
            if app.dimension == 1
                 app.ENL = zeros(size(app.NL,1),12);
                app.ENL(:,1:2) = app.NL;
                app.ENL(:,3:4) = ones(size(app.NL,1),2);
                app.ENL(1:(app.Uniform_c+1),4) = [-1];
                app.ENL(((app.Uniform_c/2)+1),3) = [-1];
             for i = 1:app.Uniform_c
                app.ENL((i*(app.Uniform_c+1)),11) = [app.Force13/(app.Uniform_c+1)];
                app.ENL(((i-1)*(app.Uniform_c+1))+1,11) = [app.Force13/(-app.Uniform_c-1)];
             end
             app.ENL((app.Uniform_c*app.Uniform_c):((app.Uniform_c+1)*app.Uniform_c),12) = [app.Force13/(app.Uniform_c+1)];


            else
            
                app.BCType = "Expansion";
            end
        end

        % Button pushed function: BeamShear
        function BeamShearButtonPushed(app, event)

            if app.dimension == 1

                app.ENL = zeros(size(app.NL,1),12);
                app.ENL(:,1:2) = app.NL;
                app.ENL(:,3:4) = ones(size(app.NL,1),2);
                app.ENL(1:app.Uniform_c+1,3:4) = [-1];
                app.ENL((app.Uniform_c)*(app.Uniform_c):((app.Uniform_c)*(app.Uniform_c))+app.Uniform_c,4) = [-1];
                app.ENL((app.Uniform_c)*(app.Uniform_c):((app.Uniform_c)*(app.Uniform_c))+app.Uniform_c,11) = [app.Force13/(app.Uniform_c+1)];

            else
            
                app.BCType = "Shear";
            end
        end

        % Button pushed function: CircleExtension
        function CircleExtensionButtonPushed(app, event)
            if app.dimension == 1

                app.ENL = zeros(size(app.NL,1),12);
                app.ENL(:,1:2) = app.NL;
                app.ENL(:,3:4) = ones(size(app.NL,1),2);
                app.ENL((app.p/2)+1,3) = [-1];
                app.ENL((app.p/2)+1+(2*app.p),3) = [-1];
                app.ENL((app.p+1):(2*app.p)+1,11) = [500/(app.p+1)];
                app.ENL(((3*app.p)+1):4*app.p,11) = [500/(-app.p-1)];
                app.ENL(1,11) = [500/(-app.p-1)];
                

            else
            
                app.BCType = "Extension";
            end
        end

        % Button pushed function: CircleExpansion
        function CircleExpansionButtonPushed(app, event)
             
            if app.dimension == 1
                app.ENL = zeros(size(app.NL,1),12);
                app.ENL(:,1:2) = app.NL;
                app.ENL(:,3:4) = ones(size(app.NL,1),2);
                app.ENL(1:(app.p+1),4) = [-1];
                app.ENL((app.p/2)+1,3) = [-1];
                app.ENL(app.p+1:(2*app.p)+1,11) =  [app.Force13/(app.p+1)];
                app.ENL(((2*app.p)+1):(3*app.p)+1,12) = [app.Force13/(app.p+1)];
                app.ENL(((3*app.p)+1):((4*app.p)),11) = [app.Force13/(-app.p-1)];
                app.ENL(1,11) = [app.Force13/(-app.p-1)];

            else
            
                app.BCType = "Expansion";
            end
        end

        % Button pushed function: CircleShear
        function CircleShearButtonPushed(app, event)
            
            if app.dimension == 1

                app.ENL = zeros(size(app.NL,1),12);
                app.ENL(:,1:2) = app.NL;
                app.ENL(:,3:4) = ones(size(app.NL,1),2);
                app.ENL(1:(app.p+1),3:4) = [-1];
                app.ENL(((2*app.p)+1):(3*app.p)+1,11) = [app.Force13/(app.p+1)];
                app.ENL(((2*app.p)+1):(3*app.p)+1,4) = [-1];

            else
            
                app.BCType = "Shear";
            end
        end

        % Button pushed function: SquareExtension
        function SquareExtensionButtonPushed(app, event)
            
            if app.dimension == 1

                CircleExtensionButtonPushed(app)

            else
            
                app.BCType = "Extension";
            end
        end

        % Button pushed function: SquareExpansion
        function SquareExpansionButtonPushed(app, event)
            
            if app.dimension == 1

                CircleExpansionButtonPushed(app)

            else
            
                app.BCType = "Expansion";
            end
        end

        % Button pushed function: SquareShear
        function SquareShearButtonPushed(app, event)
           
            if app.dimension == 1

                CircleShearButtonPushed(app)

            else
            
                app.BCType = "Shear";
            end
        end

        % Button pushed function: RhombusExtension
        function RhombusExtensionButtonPushed(app, event)
            
            if app.dimension == 1

                CircleExtensionButtonPushed(app)

            else
            
                app.BCType = "Extension";
            end
        end

        % Button pushed function: RhombusExpansion
        function RhombusExpansionButtonPushed(app, event)
            
            if app.dimension == 1

                CircleExpansionButtonPushed(app)

            else
            
                app.BCType = "Expansion";
            end
        end

        % Button pushed function: RhombusShear
        function RhombusShearButtonPushed(app, event)
           
            if app.dimension == 1

                CircleShearButtonPushed(app)

            else
            
                app.BCType = "Shear";
            end
        end

        % Button pushed function: Run2DAnalysisButton
        function Run2DAnalysisButtonPushed(app, event)

            cla(app.UIAxes_2)

            [ENL, DOFs, DOCs] = assign_BCs(app.NL2, app.BCType, app.magnitude, app.d1, app.d2);

            K = assemble_stiffness(ENL, app.EL2, app.NL2, app.EV, app.p, app.m, app.element_type);
            
            Fp = assemble_forces(ENL, app.NL2);
            
            Up = assemble_displacements(ENL, app.NL2);
            
            Kpu = K(1:DOFs,1:DOFs);
            Kpp = K(1:DOFs,DOFs+1:DOFs+DOCs);
            Kuu = K(DOFs+1:DOCs+DOFs,1:DOFs);
            Kup = K(DOFs+1:DOCs+DOFs,DOFs+1:DOCs+DOFs);
            
            F = Fp - Kpp * Up;
            
            Uu = inv(Kpu)*F;
            
            Fu = Kuu*Uu + Kup*Up;
            
            ENL = update_nodes(ENL,Uu,app.NL2,Fu);


            post_process(app.NL2, app.EL2, ENL, app.EV, app.p, app.m, app.element_type, app.stressswitch, app.UIAxes_2, app.colormap, app.edgec);
        end

        % Value changed function: StressTypeDropDown
        function StressTypeDropDownValueChanged(app, event)
            app.stressswitch = app.StressTypeDropDown.Value;
            Run2DAnalysisButtonPushed(app,event)
        end

        % Button pushed function: Clear2DAnalysisButton
        function Clear2DAnalysisButtonPushed(app, event)
            app.NoN2 = [];
            app.NoE2 = [];
            app.NL2 = [];
            app.EL2 = [];
            app.NPE2 = [];

            cla(app.UIAxes_2)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            switch selectedButton
                 case app.Jet
                    app.colormap = turbo;
                    %app.colormap(app.UIAxes_2,app.colormap);
                   
                case app.Bone
                    app.colormap = bone;
                    %app.colormap(app.UIAxes_2,app.colormap);
               
                case app.Parula
                    app.colormap = parula;
                    %app.colormap(app.UIAxes_2,app.colorMap);
                    
                case app.Autumn
                    app.colormap = autumn;
                    %app.colormap(app.UIAxes_2,app.colorMap);
            end
            Run2DAnalysisButtonPushed(app,event)
            
        end

        % Button pushed function: Run1DAnalysisButton
        function Run1DAnalysisButtonPushed(app, event)
            format long

            assign_BCs2(app); 
            
            ENL3 = app.ENL(:,3);
            ENL4 = app.ENL(:,4);
            ENL3(ENL3==0)=1;
            ENL4(ENL4==0)=1;
            app.ENL(:,3) = ENL3;
            app.ENL(:,4) = ENL4;

            K = assemble_stiffness2(app);
        
            assemble_forces2(app);
            assemble_displacements2(app);
            
            Kpu = K(1:app.DOFs, 1:app.DOFs);
            Kpp = K(1:app.DOFs, app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs, 1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);

         
            F = app.Fp - Kpp*app.Up;
            
            app.Uu = inv(Kpu)*F;  % Instead of inv(Kpu)*F to be fast...
            
            app.Fu = Kuu*app.Uu + Kup*app.Up;
            
            update_nodes2(app);
 
            app.NoN = size(app.NL,1);
            app.NoE = size(app.EL,1);

            cla(app.UIAxes_3);

            hold(app.UIAxes_3,'on');

            colormap(app.UIAxes_3,app.colormap);


            %%%%%   Plot Elements With Stress Distribution   %%%%%
            
            for i=1:app.NoE

                sigmaS = app.E *(sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/(sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2+(app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                colS = [sigmaS,sigmaS];
            
                x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                z = zeros(size(x));
            
                if app.DispDrop_2 == "Total Displacement"
                    TotalDispCol = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
                    
                    surface(app.UIAxes_3, [x;x],[y;y],[z;z],[TotalDispCol;TotalDispCol],'facecol','no','edgecol','interp','linew',4);
                
                elseif app.DispDrop_2 == "X Displacement"
                    
                    XDispCol = [abs(app.ENL(app.EL(i,1),9)) abs(app.ENL(app.EL(i,2),9))];
                    
                    surface(app.UIAxes_3, [x;x],[y;y],[z;z],[XDispCol;XDispCol],'facecol','no','edgecol','interp','linew',4);
                    
                elseif app.DispDrop_2 == "Y Displacement"   
                    
                    YDispCol = [abs(app.ENL(app.EL(i,1),10)) abs(app.ENL(app.EL(i,2),10))];
                    
                    surface(app.UIAxes_3, [x;x],[y;y],[z;z],[YDispCol;YDispCol],'facecol','no','edgecol','interp','linew',4);

                elseif app.DispDrop_2 == "Stress Distribution"
                    
                    surface(app.UIAxes_3,[x;x],[y;y],[z;z],[colS;colS],'facecol','no','edgecol', 'interp','linew', 4);


                end


            end
        end

        % Value changed function: StressTypeDropDown_2
        function StressTypeDropDown_2ValueChanged(app, event)
            app.DispDrop_2 = app.StressTypeDropDown_2.Value;
            Run1DAnalysisButtonPushed(app, event)
            
        end

        % Button pushed function: Clear1DAnalysisButton
        function Clear1DAnalysisButtonPushed(app, event)
            app.NL = [];
            app.EL = [];
            
            app.NoN = 0;
            app.NoE = 0;
            
            cla(app.UIAxes_3) 
   
            app.DOFs = 0; 
            app.DOCs = 0; 
           
            app.Up = [];
            app.Fp = [];
            app.Fu = [];
            app.Uu = [];
            app.nl = [];
           
            app.ENL = [];
        end

        % Value changed function: DisplayEdgesSwitch
        function DisplayEdgesSwitchValueChanged(app, event)
            app.edgedisp = app.DisplayEdgesSwitch.Value;
      
            if app.edgedisp == "On"
                app.edgec = "k";
            elseif app.edgedisp =="Off"
                app.edgec = "none";
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.BeamWithNoVoidTab = uitab(app.TabGroup2);
            app.BeamWithNoVoidTab.Title = 'Beam With No Void';
            app.BeamPlotButton = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamPlotButton.ButtonPushedFcn = createCallbackFcn(app, @BeamPlotButtonPushed, true);
            app.BeamPlotButton.Icon = 'IMG_0295.jpg';
            app.BeamPlotButton.Text = '';
            app.BeamExtension = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamExtension.ButtonPushedFcn = createCallbackFcn(app, @BeamExtensionButtonPushed, true);
            app.BeamExtension.Icon = 'IMG_0292.jpg';
            app.BeamExtension.Text = '';
            app.BeamExpansion = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamExpansion.ButtonPushedFcn = createCallbackFcn(app, @BeamExpansionButtonPushed, true);
            app.BeamExpansion.Icon = 'IMG_0293.jpg';
            app.BeamExpansion.Text = '';
            app.BeamShear = uibutton(app.BeamWithNoVoidTab, 'push');
            app.BeamShear.ButtonPushedFcn = createCallbackFcn(app, @BeamShearButtonPushed, true);
            app.BeamShear.Icon = 'IMG_0294.jpg';
            app.BeamShear.Text = '';
            app.aEditFieldLabel = uilabel(app.BeamWithNoVoidTab);
            app.aEditFieldLabel.Text = 'a :';
            app.Beam_aEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_aEditField.ValueChangedFcn = createCallbackFcn(app, @Beam_aEditFieldValueChanged, true);
            app.Beam_aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.BeamWithNoVoidTab);
            app.bEditFieldLabel.Text = 'b:';
            app.Beam_bEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_bEditField.ValueChangedFcn = createCallbackFcn(app, @Beam_bEditFieldValueChanged, true);
            app.Beam_bEditField.Value = 1;
            app.cEditFieldLabel = uilabel(app.BeamWithNoVoidTab);
            app.cEditFieldLabel.Text = 'c:';
            app.Beam_cEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_cEditField.ValueChangedFcn = createCallbackFcn(app, @Beam_cEditFieldValueChanged, true);
            app.Beam_cEditField.Value = 6;
            app.dEditFieldLabel = uilabel(app.BeamWithNoVoidTab);
            app.dEditFieldLabel.Text = 'd:';
            app.Beam_dEditField = uieditfield(app.BeamWithNoVoidTab, 'numeric');
            app.Beam_dEditField.ValueChangedFcn = createCallbackFcn(app, @Beam_dEditFieldValueChanged, true);
            app.Beam_dEditField.Value = 5;
            app.CircleTab = uitab(app.TabGroup2);
            app.CircleTab.Title = 'Circle';
            app.CirclePlotButton = uibutton(app.CircleTab, 'push');
            app.CirclePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CirclePlotButtonPushed, true);
            app.CirclePlotButton.Icon = 'IMG_0289.jpg';
            app.CirclePlotButton.Text = '';
            app.aEditFieldLabel_2 = uilabel(app.CircleTab);
            app.aEditFieldLabel_2.Text = 'a :';
            app.Circle_aEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_aEditField.ValueChangedFcn = createCallbackFcn(app, @Circle_aEditFieldValueChanged, true);
            app.Circle_aEditField.Value = 1;
            app.bEditFieldLabel_2 = uilabel(app.CircleTab);
            app.bEditFieldLabel_2.Text = 'b:';
            app.Circle_bEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_bEditField.ValueChangedFcn = createCallbackFcn(app, @Circle_bEditFieldValueChanged, true);
            app.Circle_bEditField.Value = 1;
            app.cEditFieldLabel_2 = uilabel(app.CircleTab);
            app.cEditFieldLabel_2.Text = 'c:';
            app.Circle_cEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_cEditField.ValueChangedFcn = createCallbackFcn(app, @Circle_cEditFieldValueChanged, true);
            app.Circle_cEditField.Value = 6;
            app.dEditFieldLabel_2 = uilabel(app.CircleTab);
            app.dEditFieldLabel_2.Text = 'd:';
            app.Circle_dEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_dEditField.ValueChangedFcn = createCallbackFcn(app, @Circle_dEditFieldValueChanged, true);
            app.Circle_dEditField.Value = 5;
            app.rEditFieldLabel = uilabel(app.CircleTab);
            app.rEditFieldLabel.Text = 'r:';
            app.Circle_rEditField = uieditfield(app.CircleTab, 'numeric');
            app.Circle_rEditField.ValueChangedFcn = createCallbackFcn(app, @Circle_rEditFieldValueChanged, true);
            app.Circle_rEditField.Value = 0.2;
            app.CircleExtension = uibutton(app.CircleTab, 'push');
            app.CircleExtension.ButtonPushedFcn = createCallbackFcn(app, @CircleExtensionButtonPushed, true);
            app.CircleExtension.Icon = 'IMG_0292.jpg';
            app.CircleExtension.Text = '';
            app.CircleExpansion = uibutton(app.CircleTab, 'push');
            app.CircleExpansion.ButtonPushedFcn = createCallbackFcn(app, @CircleExpansionButtonPushed, true);
            app.CircleExpansion.Icon = 'IMG_0293.jpg';
            app.CircleExpansion.Text = '';
            app.CircleShear = uibutton(app.CircleTab, 'push');
            app.CircleShear.ButtonPushedFcn = createCallbackFcn(app, @CircleShearButtonPushed, true);
            app.CircleShear.Icon = 'IMG_0294.jpg';
            app.CircleShear.Text = '';
            app.SquareTab = uitab(app.TabGroup2);
            app.SquareTab.Title = 'Square';
            app.SquarePlotButton = uibutton(app.SquareTab, 'push');
            app.SquarePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SquarePlotButtonPushed, true);
            app.SquarePlotButton.Icon = 'IMG_0290.jpg';
            app.SquarePlotButton.Text = '';
            app.SquareExtension = uibutton(app.SquareTab, 'push');
            app.SquareExtension.ButtonPushedFcn = createCallbackFcn(app, @SquareExtensionButtonPushed, true);
            app.SquareExtension.Icon = 'IMG_0292.jpg';
            app.SquareExtension.Text = '';
            app.SquareExpansion = uibutton(app.SquareTab, 'push');
            app.SquareExpansion.ButtonPushedFcn = createCallbackFcn(app, @SquareExpansionButtonPushed, true);
            app.SquareExpansion.Icon = 'IMG_0293.jpg';
            app.SquareExpansion.Text = '';
            app.SquareShear = uibutton(app.SquareTab, 'push');
            app.SquareShear.ButtonPushedFcn = createCallbackFcn(app, @SquareShearButtonPushed, true);
            app.SquareShear.Icon = 'IMG_0294.jpg';
            app.SquareShear.Text = '';
            app.aEditFieldLabel_3 = uilabel(app.SquareTab);
            app.aEditFieldLabel_3.Text = 'a :';
            app.Square_aEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_aEditField.ValueChangedFcn = createCallbackFcn(app, @Square_aEditFieldValueChanged, true);
            app.Square_aEditField.Value = 1;
            app.bEditFieldLabel_3 = uilabel(app.SquareTab);
            app.bEditFieldLabel_3.Text = 'b:';
            app.Square_bEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_bEditField.ValueChangedFcn = createCallbackFcn(app, @Square_bEditFieldValueChanged, true);
            app.Square_bEditField.Value = 1;
            app.cEditFieldLabel_3 = uilabel(app.SquareTab);
            app.cEditFieldLabel_3.Text = 'c:';
            app.Square_cEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_cEditField.ValueChangedFcn = createCallbackFcn(app, @Square_cEditFieldValueChanged, true);
            app.Square_cEditField.Value = 4;
            app.dEditFieldLabel_3 = uilabel(app.SquareTab);
            app.dEditFieldLabel_3.Text = 'd:';
            app.Square_dEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_dEditField.ValueChangedFcn = createCallbackFcn(app, @Square_dEditFieldValueChanged, true);
            app.Square_dEditField.Value = 4;
            app.eLabel = uilabel(app.SquareTab);
            app.eLabel.Text = 'e:';
            app.Square_eEditField = uieditfield(app.SquareTab, 'numeric');
            app.Square_eEditField.ValueChangedFcn = createCallbackFcn(app, @Square_eEditFieldValueChanged, true);
            app.Square_eEditField.Value = 0.2;
            app.RhombusTab = uitab(app.TabGroup2);
            app.RhombusTab.Title = 'Rhombus';
            app.RhombusPlotButton = uibutton(app.RhombusTab, 'push');
            app.RhombusPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RhombusPlotButtonPushed, true);
            app.RhombusPlotButton.Icon = 'IMG_0360.jpg';
            app.RhombusPlotButton.Text = '';
            app.RhombusExtension = uibutton(app.RhombusTab, 'push');
            app.RhombusExtension.ButtonPushedFcn = createCallbackFcn(app, @RhombusExtensionButtonPushed, true);
            app.RhombusExtension.Icon = 'IMG_0292.jpg';
            app.RhombusExtension.Text = '';
            app.RhombusExpansion = uibutton(app.RhombusTab, 'push');
            app.RhombusExpansion.ButtonPushedFcn = createCallbackFcn(app, @RhombusExpansionButtonPushed, true);
            app.RhombusExpansion.Icon = 'IMG_0293.jpg';
            app.RhombusExpansion.Text = '';
            app.RhombusShear = uibutton(app.RhombusTab, 'push');
            app.RhombusShear.ButtonPushedFcn = createCallbackFcn(app, @RhombusShearButtonPushed, true);
            app.RhombusShear.Icon = 'IMG_0294.jpg';
            app.RhombusShear.Text = '';
            app.aEditFieldLabel_4 = uilabel(app.RhombusTab);
            app.aEditFieldLabel_4.Text = 'a :';
            app.Rhombus_aEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_aEditField.ValueChangedFcn = createCallbackFcn(app, @Rhombus_aEditFieldValueChanged, true);
            app.Rhombus_aEditField.Value = 1;
            app.bEditFieldLabel_4 = uilabel(app.RhombusTab);
            app.bEditFieldLabel_4.Text = 'b:';
            app.Rhombus_bEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_bEditField.ValueChangedFcn = createCallbackFcn(app, @Rhombus_bEditFieldValueChanged, true);
            app.Rhombus_bEditField.Value = 1;
            app.cEditFieldLabel_4 = uilabel(app.RhombusTab);
            app.cEditFieldLabel_4.Text = 'c:';
            app.Rhombus_cEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_cEditField.ValueChangedFcn = createCallbackFcn(app, @Rhombus_cEditFieldValueChanged, true);
            app.Rhombus_cEditField.Value = 4;
            app.dEditFieldLabel_4 = uilabel(app.RhombusTab);
            app.dEditFieldLabel_4.Text = 'd:';
            app.Rhombus_dEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_dEditField.ValueChangedFcn = createCallbackFcn(app, @Rhombus_dEditFieldValueChanged, true);
            app.Rhombus_dEditField.Value = 4;
            app.eLabel_2 = uilabel(app.RhombusTab);
            app.eLabel_2.Text = 'e:';
            app.Rhombus_eEditField = uieditfield(app.RhombusTab, 'numeric');
            app.Rhombus_eEditField.ValueChangedFcn = createCallbackFcn(app, @Rhombus_eEditFieldValueChanged, true);
            app.Rhombus_eEditField.Value = 0.2;
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'1D', '2D'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2D';
            app.Switch_2 = uiswitch(app.GridLayout, 'slider');
            app.Switch_3 = uiswitch(app.GridLayout, 'slider');
            app.Switch_3.Items = {'Void', 'Inclusion'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Void';
            app.ElementTypeLabel = uilabel(app.GridLayout);
            app.ElementTypeLabel.Text = 'Element Type: ';
            app.ElementTypeDropDown = uidropdown(app.GridLayout);
            app.ElementTypeDropDown.Items = {'D2TR3N', 'D2TR6N', 'D2QU4N', 'D2QU8N', 'D2QU9N'};
            app.ElementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementTypeDropDownValueChanged, true);
            app.ElementTypeDropDown.Value = 'D2TR3N';
            app.EEditFieldLabel = uilabel(app.GridLayout);
            app.EEditFieldLabel.Text = 'E: ';
            app.EEditField = uieditfield(app.GridLayout, 'numeric');
            app.EEditField.ValueChangedFcn = createCallbackFcn(app, @EEditFieldValueChanged, true);
            app.EEditField.Value = 1;
            app.vEditFieldLabel = uilabel(app.GridLayout);
            app.vEditFieldLabel.Text = 'v: ';
            app.vEditField = uieditfield(app.GridLayout, 'numeric');
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @vEditFieldValueChanged, true);
            app.vEditField.Value = 0.3;
            app.EEditField_2Label = uilabel(app.GridLayout);
            app.EEditField_2Label.Text = 'E: ';
            app.EEditField_Inc = uieditfield(app.GridLayout, 'numeric');
            app.EEditField_Inc.ValueChangedFcn = createCallbackFcn(app, @EEditField_IncValueChanged, true);
            app.EEditField_Inc.Value = 10;
            app.vEditField_2Label = uilabel(app.GridLayout);
            app.vEditField_2Label.Text = 'v: ';
            app.vEditField_Inc = uieditfield(app.GridLayout, 'numeric');
            app.vEditField_Inc.ValueChangedFcn = createCallbackFcn(app, @vEditField_IncValueChanged, true);
            app.vEditField_Inc.Value = 0.3;
            app.BodyLabel = uilabel(app.GridLayout);
            app.BodyLabel.Text = 'Body';
            app.InclusionLabel = uilabel(app.GridLayout);
            app.InclusionLabel.Text = 'Inclusion';
            app.ofNodesEditFieldLabel = uilabel(app.GridLayout);
            app.ofNodesEditFieldLabel.Text = '# of Nodes';
            app.ofNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.ofNodesEditField.ValueChangedFcn = createCallbackFcn(app, @ofNodesEditFieldValueChanged, true);
            app.ofNodesEditField.Editable = 'off';
            app.ofElementsEditFieldLabel = uilabel(app.GridLayout);
            app.ofElementsEditFieldLabel.Text = {'# of Elements'; ''};
            app.ofElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @ofElementsEditFieldValueChanged, true);
            app.ofElementsEditField.Editable = 'off';
            app.DeformationMagnifierLabel = uilabel(app.GridLayout);
            app.DeformationMagnifierLabel.Text = 'Deformation Magnifier:';
            app.DeformationMagnifierEditField = uieditfield(app.GridLayout, 'numeric');
            app.DeformationMagnifierEditField.ValueChangedFcn = createCallbackFcn(app, @DeformationMagnifierEditFieldValueChanged, true);
            app.DeformationMagnifierEditField.Value = 0.1;
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Jet = uitogglebutton(app.ButtonGroup);
            app.Jet.Icon = 'Rainbow.gif';
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Bone = uitogglebutton(app.ButtonGroup);
            app.Bone.Icon = 'Gey.gif';
            app.Bone.Text = '';
            app.Parula = uitogglebutton(app.ButtonGroup);
            app.Parula.Icon = 'YellowBlue.gif';
            app.Parula.Text = '';
            app.Autumn = uitogglebutton(app.ButtonGroup);
            app.Autumn.Icon = 'Autumn.gif';
            app.Autumn.Text = '';
            app.StressTypeDropDown = uidropdown(app.GridLayout);
            app.StressTypeDropDown.Items = {'Stress_xx', 'Stress_xy', 'Stress_yx', 'Stress_yy', 'Strain_xx', 'Strain_xy', 'Strain_yx', 'Strain_yy', 'Disp_x', 'Disp_y'};
            app.StressTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StressTypeDropDownValueChanged, true);
            app.StressTypeDropDown.Value = 'Stress_xx';
            app.HowtouseButton = uibutton(app.GridLayout, 'push');
            app.HowtouseButton.Text = 'How to use?';
            app.Run2DAnalysisButton = uibutton(app.GridLayout, 'push');
            app.Run2DAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Run2DAnalysisButtonPushed, true);
            app.Run2DAnalysisButton.Text = 'Run 2D Analysis';
            app.Run1DAnalysisButton = uibutton(app.GridLayout, 'push');
            app.Run1DAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Run1DAnalysisButtonPushed, true);
            app.Run1DAnalysisButton.Text = 'Run 1D Analysis';
            app.Clear2DAnalysisButton = uibutton(app.GridLayout, 'push');
            app.Clear2DAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Clear2DAnalysisButtonPushed, true);
            app.Clear2DAnalysisButton.Text = 'Clear 2D Analysis';
            app.Clear1DAnalysisButton = uibutton(app.GridLayout, 'push');
            app.Clear1DAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Clear1DAnalysisButtonPushed, true);
            app.Clear1DAnalysisButton.Text = 'Clear 1D Analysis';
            app.HowtouseButton_2 = uibutton(app.GridLayout, 'push');
            app.HowtouseButton_2.Text = 'How to use?';
            app.StressTypeDropDown_2 = uidropdown(app.GridLayout);
            app.StressTypeDropDown_2.Items = {'Total Displacement', 'X Displacement', 'Y Displacement', 'Stress Distribution'};
            app.StressTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StressTypeDropDown_2ValueChanged, true);
            app.StressTypeDropDown_2.Value = 'Total Displacement';
            app.DisplayEdgesLabel = uilabel(app.GridLayout);
            app.DisplayEdgesLabel.Text = 'Display Edges:';
            app.DisplayEdgesSwitch = uiswitch(app.GridLayout, 'slider');
            app.DisplayEdgesSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayEdgesSwitchValueChanged, true);
            app.DisplayEdgesSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEM_Project_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end